classdef shulte_table_ui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        StatusLabel                     matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        Table                           matlab.ui.container.Tab
        GridLayoutTablePage             matlab.ui.container.GridLayout
        GridLayoutTableElement          matlab.ui.container.GridLayout
        UITable                         matlab.ui.control.Table
        Lamp                            matlab.ui.control.Lamp
        GridLayoutTimeAndButtons        matlab.ui.container.GridLayout
        ResetButton                     matlab.ui.control.Button
        NextButton                      matlab.ui.control.Button
        ResultArea                      matlab.ui.control.TextArea
        TimeLabel                       matlab.ui.control.Label
        PlotTab                         matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        UIAxes                          matlab.ui.control.UIAxes
        OptionsTab                      matlab.ui.container.Tab
        GridLayoutOptions               matlab.ui.container.GridLayout
        UpperCaseLettersCheckBox        matlab.ui.control.CheckBox
        TableBackgroundColorPicker      matlab.ui.control.ColorPicker
        TableBackgroundFontColorPicker  matlab.ui.control.ColorPicker
        BackgroundColorColorPickerLabel  matlab.ui.control.Label
        FontColorColorPicker            matlab.ui.control.ColorPicker
        FontColorColorPickerLabel       matlab.ui.control.Label
        FontItalicButton                matlab.ui.control.StateButton
        FontAngleLabel                  matlab.ui.control.Label
        FontWeightLabel                 matlab.ui.control.Label
        FontBoldButton                  matlab.ui.control.StateButton
        HexadecimalModeLabel            matlab.ui.control.Label
        HexNumbersCheckBox              matlab.ui.control.CheckBox
        FontNameDropDown                matlab.ui.control.DropDown
        FontNameDropDownLabel           matlab.ui.control.Label
        FontSizeEditField               matlab.ui.control.NumericEditField
        FontSizeEditFieldLabel          matlab.ui.control.Label
        ComplexitySpinner               matlab.ui.control.Spinner
        ComplexitySpinnerLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        options (1,1) model.Options
        shulteTable model.ShulteTable
        trainingTimer timer
        elapsedTimer controller.ElapsedTimer
        nextNumber uint32
        typoSoundEffect double
        typoSoundEffectSampleRate (1,1) double

        beginTime (1,1) datetime
        taskDurations (2, :) double
        mistakes (1,1) uint32
    end

    methods (Static)

        function state = fontToButtonState(fontSetting)
            switch fontSetting
                case 'normal'
                    state = false;
                case {'bold', 'italic'}
                    state = true;
            end
        end

        function value = buttonStateToFontWeight(state)
            switch state
                case false
                    value = 'normal';
                case true
                    value = 'bold';
            end
        end

        function value = buttonStateToFontAngle(state)
            switch state
                case false
                    value = 'normal';
                case true
                    value = 'italic';
            end
        end
    end
    
    methods (Access = private)
        
        function initTable(app, repeatNumber)
            if nargin == 2
                app.shulteTable = model.ShulteTable(app.options.complexity, repeatNumber);
                app.TimeLabel.Text = "-- : --";
                app.beginTime = datetime('now');
            else
                app.shulteTable = model.ShulteTable(app.options.complexity);
            end
            numbers = app.shulteTable.numbers;

            if app.options.hexNumbers && ~app.options.hexLettersUpperCase
                data = arrayfun(@(x) ...
                    sprintf('%s%02x', repmat(char(0160), 1, 2), x), ...
                    numbers, 'UniformOutput', false);
            elseif app.options.hexNumbers && app.options.hexLettersUpperCase
                data = arrayfun(@(x) ...
                    sprintf('%s%02X', repmat(char(0160), 1, 2), x), ...
                    numbers, 'UniformOutput', false);
            else
                data = arrayfun(@(x) ...
                    sprintf('%s%02d', repmat(char(0160), 1, 2), x), ...
                    numbers, 'UniformOutput', false);
            end

            app.UITable.Data = data;
            app.UITable.ColumnWidth = 'fit';

            app.UITable.Selection = [];
        end

        function app = switchLamp(app, state)
            switch (state)
                case "on"
                    app.Lamp.Color = controller.hex2rgb("#1d8e49");
                case "off"
                    app.Lamp.Color = controller.hex2rgb("#808080");
            end
        end

        function app = stopTrainingTimer(app)
            if ~isempty(app.trainingTimer) && isa(app.trainingTimer, 'timer') && isvalid(app.trainingTimer)
                stop(app.trainingTimer);
                delete(app.trainingTimer);
            end
        end

        function app = startTrainingTimer(app)
            app.stopTrainingTimer();

            app.trainingTimer = timer;
            app.trainingTimer.ExecutionMode = 'fixedSpacing';
            app.trainingTimer.Period = 1;
            app.trainingTimer.TimerFcn = @(src, event) app.updateTime();

            app.elapsedTimer = controller.ElapsedTimer();
            app.elapsedTimer.start();
            app.trainingTimer.start();
        end

        function app = updateTime(app)
            elapsed = app.elapsedTimer.getElapsed();
            [minutes, seconds] = controller.toMinutesAndSecconds(elapsed);
            app.TimeLabel.Text = sprintf("%02d : %02d", minutes, seconds);
        end

        function app = loadAssets(app)
            soundFile = fullfile(model.Config.typoSoundEffect{:});
            if exist(soundFile, 'file')
                [app.typoSoundEffect, app.typoSoundEffectSampleRate] = audioread(soundFile);
            else
                uialert(app.UIFigure, ['File not found: ' soundFile], 'Missing File');
            end
        end

        function app = incorrectrWarn(app)
            sound(app.typoSoundEffect, app.typoSoundEffectSampleRate);
        end

        function app = onCompleteTask(app)
            app.elapsedTimer.pause();
            app.stopTrainingTimer();
            
            duration = app.elapsedTimer.getElapsed();
            elapsedTime = minutes(datetime('now') - app.beginTime);

            app.ResultArea.Value{end + 1} = sprintf('%03.1f', duration);
            app.switchLamp('on');
            app.taskDurations(:, end + 1) = [elapsedTime; duration];
            plot(app.UIAxes, app.taskDurations(1, :), app.taskDurations(2, :), 'k.-');
        end

        function app = setOptionsPage(app, options)
            arguments
                app shulte_table_ui
                options model.Options
            end
            app.ComplexitySpinner.Value = double(options.complexity);

            app.HexNumbersCheckBox.Value = options.hexNumbers;
            app.UpperCaseLettersCheckBox.Value = options.hexLettersUpperCase;

            app.FontNameDropDown.Items = listfonts;
            app.FontNameDropDown.Value = options.fontName;
            app.FontBoldButton.Value = shulte_table_ui.fontToButtonState(options.fontWeight);
            app.FontItalicButton.Value = shulte_table_ui.fontToButtonState(options.fontAngle);
            app.FontColorColorPicker.Value = options.fontColor;
            app.TableBackgroundFontColorPicker.Value = options.backgroundFontColor;
            app.TableBackgroundColorPicker.Value = options.backgroundColor;

            app.options = options;
        end

        function app = storeOptions(app)
            app.options.storeOptions();
        end
        
        function app = selectPreviousCell(app)
            expectedNext = app.shulteTable.nextNumber;
            if expectedNext == 1
                app.UITable.Selection = [];
                return
            end

            expeded = expectedNext - 1;

            comparator = @(x) str2double(replace(x, char(160), '')) == expeded;
            data = app.UITable.Data;
            [col, row] = find(comparator(data));
            app.UITable.Selection = [col, row];
        end

        function storeToDatabase(app)
            if numel(app.taskDurations) == 0
                return
            end

            session = model.Session();
            session.beginAt = app.beginTime;
            session.finishAt = app.beginTime + app.taskDurations(1, end);
            session.mistakes = app.mistakes;
            session.complexity = app.shulteTable.size;
            session.taskDurations = app.taskDurations;

            database.storeSessionToDatabase(session);
        end
        
        function setTableApearence(app)
            app.UITable.FontName = app.options.fontName;
            app.UITable.FontSize = app.options.fontSize;
            app.UITable.FontWeight = app.options.fontWeight;
            app.UITable.FontAngle = app.options.fontAngle;
            app.UITable.ForegroundColor = app.options.fontColor;
            app.UITable.BackgroundColor = [app.options.backgroundFontColor; app.options.backgroundColor];
        end
        
        function resetTable(app)
            app.stopTrainingTimer();
            app.taskDurations = [];
            app.initTable(0);
            app.ResultArea.Value = '';
            app.mistakes = 0;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            database.initDatabase();

            app.options = app.options.loadOptions();
            app.setOptionsPage(app.options);
            app.loadAssets();
            app.setTableApearence();

            app.initTable(0);
            app.switchLamp("off");
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.initTable();
            app.startTrainingTimer();
            app.switchLamp('off');
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            if app.shulteTable.isCompleted()
                return
            end

            selection = num2cell(app.UITable.Selection);
            element = app.UITable.Data{selection{:}};
            value = replace(element, char(160), '');
            if app.options.hexNumbers
                actualNumber = hex2dec(value);
            else
                actualNumber = str2double(value);
            end

            if app.shulteTable.isExpecedCell(actualNumber)
                app.shulteTable.next();
            else
                app.incorrectrWarn();
                app.selectPreviousCell();
                app.mistakes = app.mistakes + 1;
            end

            if app.shulteTable.isCompleted()
                app.onCompleteTask();
            end
        end

        % Value changed function: ComplexitySpinner, HexNumbersCheckBox, 
        % ...and 1 other component
        function ShulteTableOptionsChanged(app, event)
            value = app.ComplexitySpinner.Value;
            app.options.complexity = value;
            app.options.hexNumbers = app.HexNumbersCheckBox.Value;
            app.options.hexLettersUpperCase = app.UpperCaseLettersCheckBox.Value;
            
            app.options.storeOptions();

            app.storeToDatabase();
            app.resetTable();
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.resetTable();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.storeToDatabase();
            
            delete(app);
        end

        % Value changed function: FontBoldButton, FontColorColorPicker, 
        % ...and 5 other components
        function ApearenceChanged(app, event)
            app.options.fontName = app.FontNameDropDown.Value;
            app.options.fontSize = app.FontSizeEditField.Value;
            app.options.fontWeight = shulte_table_ui.buttonStateToFontWeight(app.FontBoldButton.Value);
            app.options.fontAngle = shulte_table_ui.buttonStateToFontAngle(app.FontItalicButton.Value);

            app.setTableApearence();

            app.options.storeOptions();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Table = uitab(app.TabGroup);
            app.Table.Title = 'Table';
            app.GridLayoutTablePage = uigridlayout(app.Table);
            app.GridLayoutTimeAndButtons = uigridlayout(app.GridLayoutTablePage);
            app.TimeLabel = uilabel(app.GridLayoutTimeAndButtons);
            app.TimeLabel.Text = 'Time';
            app.ResultArea = uitextarea(app.GridLayoutTimeAndButtons);
            app.NextButton = uibutton(app.GridLayoutTimeAndButtons, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.ResetButton = uibutton(app.GridLayoutTimeAndButtons, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.GridLayoutTableElement = uigridlayout(app.GridLayoutTablePage);
            app.Lamp = uilamp(app.GridLayoutTableElement);
            app.UITable = uitable(app.GridLayoutTableElement);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.GridLayout3 = uigridlayout(app.PlotTab);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'Productivity')
            xlabel(app.UIAxes, 'Attempt')
            ylabel(app.UIAxes, 'Time')
            zlabel(app.UIAxes, 'Z')
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.GridLayoutOptions = uigridlayout(app.OptionsTab);
            app.ComplexitySpinnerLabel = uilabel(app.GridLayoutOptions);
            app.ComplexitySpinnerLabel.Text = 'Complexity';
            app.ComplexitySpinner = uispinner(app.GridLayoutOptions);
            app.ComplexitySpinner.Limits = [2 10];
            app.ComplexitySpinner.ValueChangedFcn = createCallbackFcn(app, @ShulteTableOptionsChanged, true);
            app.ComplexitySpinner.Value = 5;
            app.FontSizeEditFieldLabel = uilabel(app.GridLayoutOptions);
            app.FontSizeEditFieldLabel.Text = 'Font Size';
            app.FontSizeEditField = uieditfield(app.GridLayoutOptions, 'numeric');
            app.FontSizeEditField.Limits = [4 200];
            app.FontSizeEditField.ValueChangedFcn = createCallbackFcn(app, @ApearenceChanged, true);
            app.FontSizeEditField.Value = 18;
            app.FontNameDropDownLabel = uilabel(app.GridLayoutOptions);
            app.FontNameDropDownLabel.Text = 'Font Name';
            app.FontNameDropDown = uidropdown(app.GridLayoutOptions);
            app.FontNameDropDown.Items = {'Calibri', 'Arial', 'Consolas'};
            app.FontNameDropDown.ValueChangedFcn = createCallbackFcn(app, @ApearenceChanged, true);
            app.FontNameDropDown.Value = 'Calibri';
            app.HexNumbersCheckBox = uicheckbox(app.GridLayoutOptions);
            app.HexNumbersCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShulteTableOptionsChanged, true);
            app.HexNumbersCheckBox.Text = '';
            app.HexadecimalModeLabel = uilabel(app.GridLayoutOptions);
            app.HexadecimalModeLabel.Text = 'Hexadecimal Mode';
            app.FontBoldButton = uibutton(app.GridLayoutOptions, 'state');
            app.FontBoldButton.ValueChangedFcn = createCallbackFcn(app, @ApearenceChanged, true);
            app.FontBoldButton.Text = 'Bolt';
            app.FontWeightLabel = uilabel(app.GridLayoutOptions);
            app.FontWeightLabel.Text = 'Font Weight';
            app.FontAngleLabel = uilabel(app.GridLayoutOptions);
            app.FontAngleLabel.Text = 'Font Angle';
            app.FontItalicButton = uibutton(app.GridLayoutOptions, 'state');
            app.FontItalicButton.ValueChangedFcn = createCallbackFcn(app, @ApearenceChanged, true);
            app.FontItalicButton.Text = 'Italic';
            app.FontColorColorPickerLabel = uilabel(app.GridLayoutOptions);
            app.FontColorColorPickerLabel.Text = 'Font Color';
            app.FontColorColorPicker = uicolorpicker(app.GridLayoutOptions);
            app.FontColorColorPicker.ValueChangedFcn = createCallbackFcn(app, @ApearenceChanged, true);
            app.BackgroundColorColorPickerLabel = uilabel(app.GridLayoutOptions);
            app.BackgroundColorColorPickerLabel.Text = 'Background Color';
            app.TableBackgroundFontColorPicker = uicolorpicker(app.GridLayoutOptions);
            app.TableBackgroundFontColorPicker.ValueChangedFcn = createCallbackFcn(app, @ApearenceChanged, true);
            app.TableBackgroundColorPicker = uicolorpicker(app.GridLayoutOptions);
            app.TableBackgroundColorPicker.ValueChangedFcn = createCallbackFcn(app, @ApearenceChanged, true);
            app.UpperCaseLettersCheckBox = uicheckbox(app.GridLayoutOptions);
            app.UpperCaseLettersCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShulteTableOptionsChanged, true);
            app.UpperCaseLettersCheckBox.Text = 'uppercase letters A–F';
            app.StatusLabel = uilabel(app.GridLayout);
            app.StatusLabel.Text = 'Status';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = shulte_table_ui

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end